classdef info_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RecordingInfoUIFigure         matlab.ui.Figure
        MouseInfosPanel               matlab.ui.container.Panel
        MousenumberLabel              matlab.ui.control.Label
        MousenumberEditField          matlab.ui.control.NumericEditField
        MouselabelLabel               matlab.ui.control.Label
        MouselabelEditField           matlab.ui.control.EditField
        MousecageLabel                matlab.ui.control.Label
        MousecageEditField            matlab.ui.control.EditField
        MouseadditionalinfoLabel      matlab.ui.control.Label
        MouseadditionalinfoEditField  matlab.ui.control.EditField
        GenotypeLabel                 matlab.ui.control.Label
        GenotypeEditField             matlab.ui.control.EditField
        TreatmentEditFieldLabel       matlab.ui.control.Label
        TreatmentEditField            matlab.ui.control.EditField
        GroupEditFieldLabel           matlab.ui.control.Label
        GroupEditField                matlab.ui.control.EditField
        EyeLabel                      matlab.ui.control.Label
        EyeDropDown                   matlab.ui.control.DropDown
        AcceptButton                  matlab.ui.control.Button
        CommentsTextAreaLabel         matlab.ui.control.Label
        CommentsTextArea              matlab.ui.control.TextArea
        TimepointEditField            matlab.ui.control.EditField
        TimepointDropDownLabel        matlab.ui.control.Label
        TimepointDropDown             matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        callingApp % Description
        info % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, folderPath)
            app.callingApp = mainApp;
            
            % Try to find default informations from folderPath
            pathParts = strsplit(folderPath,'\');
            temp = strsplit(pathParts{end-1},'_');
            if numel(temp{1}) == 3
                app.MousenumberEditField.Value = 0;
                app.MouselabelEditField.Value = temp{1};
            elseif numel(temp{1}) < 3
                app.MousenumberEditField.Value = str2double(temp{1});
                app.MouselabelEditField.Value = '';
            end
            temp = strsplit(pathParts{end-2},'_');
            app.MousecageEditField.Value = temp{end};
            % Fill out the default values
            try
                m = matfile('prefs.mat');
                defInfo = m.defInfo;
                app.GenotypeEditField.Value = defInfo.genotype;
                app.TreatmentEditField.Value = defInfo.treatment;
                app.GroupEditField.Value = defInfo.group;
                app.TimepointEditField.Value = defInfo.timepoint;
            catch
            end
            

        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            % Check for validity of the infos
            app.info.dateOfAnalysis = datestr(now,'yyyy-mm-dd_hh-MM-ss');
            app.info.miceNumber = app.MousenumberEditField.Value;
            app.info.miceLabel = app.MouselabelEditField.Value;
            app.info.miceCage = app.MousecageEditField.Value;
            app.info.additionalInfo = app.MouseadditionalinfoEditField.Value;
            app.info.genotype = app.GenotypeEditField.Value;
            app.info.eye = app.EyeDropDown.Value;
            app.info.treatment = app.TreatmentEditField.Value;
            app.info.group = app.GroupEditField.Value;
            if strcmpi(app.TimepointEditField.Enable, 'on')
                if isempty(app.TimepointEditField.Value)
                    tit = 'Empty timepoint';
                    msg = ['You selected "other" in timepoints, however you specified an empty value. '...
                        'Please fill out the timepoint field and act as an adult human being.'];
                    opts = struct('WindowStyle','modal','Interpreter','tex');
                    errordlg(msg,tit,opts)
                    return
                end
                app.info.timepoint = app.TimepointEditField.Value;
            else
                app.info.timepoint = app.TimepointDropDown.Value;
            end
            app.info.comments = app.CommentsTextArea.Value;
            % Put the user defined info in the main app property
            app.callingApp.userDefinedInfo = app.info;
            % Saving the variables as the next default
            try
                m = matfile('prefs.mat','Writable',true);
                defInfo = m.defInfo;
                defInfo.genotype = app.GenotypeEditField.Value;
                defInfo.treatment = app.TreatmentEditField.Value;
                defInfo.group = app.GroupEditField.Value;
                defInfo.timepoint = app.TimepointEditField.Value;
                m.defInfo = defInfo;
            catch
            end
            %close the dialog box
            RecordingInfoUIFigureCloseRequest(app, event)
        end

        % Close request function: RecordingInfoUIFigure
        function RecordingInfoUIFigureCloseRequest(app, event)
            app.callingApp.ExportAnalysisButton.Enable = 'on';
            delete(app)
        end

        % Value changed function: TimepointDropDown
        function TimepointDropDownValueChanged(app, event)
            value = app.TimepointDropDown.Value;
            app.TimepointEditField.Enable = 'off';
            if strcmpi(value, 'other')
                app.TimepointEditField.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RecordingInfoUIFigure = uifigure;
            app.RecordingInfoUIFigure.Name = 'Recording Info';
            app.RecordingInfoUIFigure.CloseRequestFcn = createCallbackFcn(app, @RecordingInfoUIFigureCloseRequest, true);
            app.MouseInfosPanel = uipanel(app.RecordingInfoUIFigure);
            app.MouseInfosPanel.Title = 'Mouse Infos';
            app.MousenumberLabel = uilabel(app.MouseInfosPanel);
            app.MousenumberLabel.Text = 'Mouse number:';
            app.MousenumberEditField = uieditfield(app.MouseInfosPanel, 'numeric');
            app.MouselabelLabel = uilabel(app.MouseInfosPanel);
            app.MouselabelLabel.Text = 'Mouse label:';
            app.MouselabelEditField = uieditfield(app.MouseInfosPanel, 'text');
            app.MousecageLabel = uilabel(app.MouseInfosPanel);
            app.MousecageLabel.Text = 'Mouse cage:';
            app.MousecageEditField = uieditfield(app.MouseInfosPanel, 'text');
            app.MouseadditionalinfoLabel = uilabel(app.MouseInfosPanel);
            app.MouseadditionalinfoLabel.Text = 'Mouse additional info:';
            app.MouseadditionalinfoEditField = uieditfield(app.MouseInfosPanel, 'text');
            app.GenotypeLabel = uilabel(app.MouseInfosPanel);
            app.GenotypeLabel.Text = 'Genotype:';
            app.GenotypeEditField = uieditfield(app.MouseInfosPanel, 'text');
            app.TreatmentEditFieldLabel = uilabel(app.MouseInfosPanel);
            app.TreatmentEditFieldLabel.Text = 'Treatment:';
            app.TreatmentEditField = uieditfield(app.MouseInfosPanel, 'text');
            app.GroupEditFieldLabel = uilabel(app.MouseInfosPanel);
            app.GroupEditFieldLabel.Text = 'Group:';
            app.GroupEditField = uieditfield(app.MouseInfosPanel, 'text');
            app.EyeLabel = uilabel(app.RecordingInfoUIFigure);
            app.EyeLabel.Text = 'Eye:';
            app.EyeDropDown = uidropdown(app.RecordingInfoUIFigure);
            app.EyeDropDown.Items = {'contra', 'ipsi', 'N/A'};
            app.EyeDropDown.Value = 'contra';
            app.AcceptButton = uibutton(app.RecordingInfoUIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.CommentsTextAreaLabel = uilabel(app.RecordingInfoUIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments:';
            app.CommentsTextArea = uitextarea(app.RecordingInfoUIFigure);
            app.TimepointEditField = uieditfield(app.RecordingInfoUIFigure, 'text');
            app.TimepointEditField.Enable = 'off';
            app.TimepointEditField.Value = 'ciao';
            app.TimepointDropDownLabel = uilabel(app.RecordingInfoUIFigure);
            app.TimepointDropDownLabel.Text = 'Timepoint';
            app.TimepointDropDown = uidropdown(app.RecordingInfoUIFigure);
            app.TimepointDropDown.Items = {'N/A', 'bin', 'post_MD', 'pre', 'post', 'other'};
            app.TimepointDropDown.ValueChangedFcn = createCallbackFcn(app, @TimepointDropDownValueChanged, true);
            app.TimepointDropDown.Value = 'N/A';
        end
    end

    methods (Access = public)

        % Construct app
        function app = info_dialog(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RecordingInfoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RecordingInfoUIFigure)
        end
    end
end