classdef info_edit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        editInfoUIFigure              matlab.ui.Figure
        LoadButton                    matlab.ui.control.Button
        EditinfoPanel                 matlab.ui.container.Panel
        MousenumberEditFieldLabel     matlab.ui.control.Label
        MousenumberEditField          matlab.ui.control.NumericEditField
        MouselabelEditFieldLabel      matlab.ui.control.Label
        MouselabelEditField           matlab.ui.control.EditField
        MousecageEditFieldLabel       matlab.ui.control.Label
        MousecageEditField            matlab.ui.control.EditField
        MouseadditionalinfoEditFieldLabel  matlab.ui.control.Label
        MouseadditionalinfoEditField  matlab.ui.control.EditField
        GenotypeEditFieldLabel        matlab.ui.control.Label
        GenotypeEditField             matlab.ui.control.EditField
        TreatmentEditFieldLabel       matlab.ui.control.Label
        TreatmentEditField            matlab.ui.control.EditField
        GroupEditFieldLabel           matlab.ui.control.Label
        GroupEditField                matlab.ui.control.EditField
        EyeDropDownLabel              matlab.ui.control.Label
        EyeDropDown                   matlab.ui.control.DropDown
        CommentsTextAreaLabel         matlab.ui.control.Label
        CommentsTextArea              matlab.ui.control.TextArea
        TimepointEditFieldLabel       matlab.ui.control.Label
        TimepointEditField            matlab.ui.control.EditField
        SaveButton                    matlab.ui.control.Button
        CurrentFileLabel              matlab.ui.control.Label
        filenameLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        deafultPath             % Default path where to load files
        currentFile             % File selected to load
        loadedInfos             % Info of the loaded matfile
        editedInfos             % Edited info to save in the mat file
        
    end
    
    methods (Access = private)
        
        function fillGUIfields(app)
            app.MousenumberEditField.Value = app.loadedInfos.miceNumber;
            app.MouselabelEditField.Value = app.loadedInfos.miceLabel;
            app.MousecageEditField.Value = app.loadedInfos.miceCage;
            if isfield(app.loadedInfos, 'additionalInfo')
                app.MouseadditionalinfoEditField.Value = app.loadedInfos.additionalInfo;
            end
            app.EyeDropDown.Value = app.loadedInfos.eye;
            app.GenotypeEditField.Value = app.loadedInfos.genotype;
            app.TreatmentEditField.Value = app.loadedInfos.treatment;
            app.GroupEditField.Value = app.loadedInfos.group;
            app.TimepointEditField.Value = app.loadedInfos.timepoint;
            app.CommentsTextArea.Value = app.loadedInfos.comments;
        end
        
        function enableAll(app,parameter)
            app.MousenumberEditField.Enable = parameter;
            app.MouselabelEditField.Enable = parameter;
            app.MousecageEditField.Enable = parameter;
            app.MouseadditionalinfoEditField.Enable = parameter;
            app.EyeDropDown.Enable = parameter;
            app.GenotypeEditField.Enable = parameter;
            app.TreatmentEditField.Enable = parameter;
            app.GroupEditField.Enable = parameter;
            app.TimepointEditField.Enable = parameter;
            app.CommentsTextArea.Enable = parameter;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                m = matfile('prefs.mat','Writable',true);
                app.deafultPath = m.defPath;
            catch
                app.deafultPath = 'C:\';
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load the file from the deafult path and update the default path saved on disk
            app.LoadButton.Enable = 'off';
            enableAll(app,'off')
            app.filenameLabel.Text = 'filename...';
            [FileName,PathName,FilterIndex] = uigetfile(app.deafultPath,'MultiSelect','off');
            if FilterIndex == 0
                app.currentFile = '';
                app.LoadButton.Enable = 'on';
                return
            end
            app.currentFile = FileName;
            app.deafultPath = PathName;
            try
                m = matfile('prefs.mat','Writable',true);
                m.defPath = app.deafultPath;
            catch
            end
            % Check wether the selected file is a valid analysis file
            m = matfile([PathName FileName]);
            if ~misField(m,'info')
                msg = 'The selected file is not a valid analysis file!';
                tit = 'Error';
                uialert(app.editInfoUIFigure,msg,tit);
                app.LoadButton.Enable = 'on';
                return
            end
            app.loadedInfos = m.info;
            % Fill the GUI with the loaded infos
            fillGUIfields(app)
            enableAll(app,'on')
            app.filenameLabel.Text = app.currentFile;
            app.LoadButton.Enable = 'on';
            app.SaveButton.Enable = 'on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Check for validity of the infos
            if strcmpi(app.filenameLabel.Text,'filename...') || isempty(app.currentFile)
                msg = 'No analysis file loaded for editing!';
                tit = 'Error';
                uialert(app.editInfoUIFigure,msg,tit);
                return
            end
            % Collect the edited infos in editedInfos
            app.editedInfos.dateOfAnalysis = datestr(now,'yyyy-mm-dd_hh-MM-ss');
            app.editedInfos.miceNumber = app.MousenumberEditField.Value;
            app.editedInfos.miceLabel = app.MouselabelEditField.Value;
            app.editedInfos.miceCage = app.MousecageEditField.Value;
            app.editedInfos.additionalInfo = app.MouseadditionalinfoEditField.Value;
            app.editedInfos.genotype = app.GenotypeEditField.Value;
            app.editedInfos.eye = app.EyeDropDown.Value;
            app.editedInfos.treatment = app.TreatmentEditField.Value;
            app.editedInfos.group = app.GroupEditField.Value;
            app.editedInfos.timepoint = app.TimepointEditField.Value;
            app.editedInfos.comments = app.CommentsTextArea.Value;
            
            m = matfile([app.deafultPath app.currentFile],'Writable',true);
            m.info = app.editedInfos;
            
            msg = 'Analysis file correctly updated!';
            tit = 'Success';
            uialert(app.editInfoUIFigure,msg,tit,'Icon','success');
            enableAll(app,'off')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.editInfoUIFigure = uifigure;
            app.editInfoUIFigure.Name = 'edit Info';
            app.LoadButton = uibutton(app.editInfoUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load an analysis file to edit the informations'};
            app.LoadButton.Text = 'Load';
            app.EditinfoPanel = uipanel(app.editInfoUIFigure);
            app.EditinfoPanel.Title = 'Edit info';
            app.MousenumberEditFieldLabel = uilabel(app.EditinfoPanel);
            app.MousenumberEditFieldLabel.Text = 'Mouse number:';
            app.MousenumberEditField = uieditfield(app.EditinfoPanel, 'numeric');
            app.MousenumberEditField.Limits = [0 Inf];
            app.MousenumberEditField.RoundFractionalValues = 'on';
            app.MousenumberEditField.Enable = 'off';
            app.MouselabelEditFieldLabel = uilabel(app.EditinfoPanel);
            app.MouselabelEditFieldLabel.Text = 'Mouse label:';
            app.MouselabelEditField = uieditfield(app.EditinfoPanel, 'text');
            app.MouselabelEditField.Enable = 'off';
            app.MousecageEditFieldLabel = uilabel(app.EditinfoPanel);
            app.MousecageEditFieldLabel.Text = 'Mouse cage:';
            app.MousecageEditField = uieditfield(app.EditinfoPanel, 'text');
            app.MousecageEditField.Enable = 'off';
            app.MouseadditionalinfoEditFieldLabel = uilabel(app.EditinfoPanel);
            app.MouseadditionalinfoEditFieldLabel.Text = 'Mouse additional info:';
            app.MouseadditionalinfoEditField = uieditfield(app.EditinfoPanel, 'text');
            app.MouseadditionalinfoEditField.Enable = 'off';
            app.GenotypeEditFieldLabel = uilabel(app.EditinfoPanel);
            app.GenotypeEditFieldLabel.Text = 'Genotype:';
            app.GenotypeEditField = uieditfield(app.EditinfoPanel, 'text');
            app.GenotypeEditField.Enable = 'off';
            app.TreatmentEditFieldLabel = uilabel(app.EditinfoPanel);
            app.TreatmentEditFieldLabel.Text = 'Treatment:';
            app.TreatmentEditField = uieditfield(app.EditinfoPanel, 'text');
            app.TreatmentEditField.Enable = 'off';
            app.GroupEditFieldLabel = uilabel(app.EditinfoPanel);
            app.GroupEditFieldLabel.Text = 'Group:';
            app.GroupEditField = uieditfield(app.EditinfoPanel, 'text');
            app.GroupEditField.Enable = 'off';
            app.EyeDropDownLabel = uilabel(app.EditinfoPanel);
            app.EyeDropDownLabel.Text = 'Eye:';
            app.EyeDropDown = uidropdown(app.EditinfoPanel);
            app.EyeDropDown.Items = {'contra', 'ipsi', 'N/A'};
            app.EyeDropDown.Enable = 'off';
            app.EyeDropDown.Value = 'N/A';
            app.CommentsTextAreaLabel = uilabel(app.EditinfoPanel);
            app.CommentsTextAreaLabel.Text = 'Comments:';
            app.CommentsTextArea = uitextarea(app.EditinfoPanel);
            app.CommentsTextArea.Enable = 'off';
            app.TimepointEditFieldLabel = uilabel(app.EditinfoPanel);
            app.TimepointEditFieldLabel.Text = 'Timepoint:';
            app.TimepointEditField = uieditfield(app.EditinfoPanel, 'text');
            app.TimepointEditField.Enable = 'off';
            app.SaveButton = uibutton(app.editInfoUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Save the edited informations in a new analysis file'};
            app.SaveButton.Text = 'Save';
            app.CurrentFileLabel = uilabel(app.editInfoUIFigure);
            app.CurrentFileLabel.Text = 'Current File:';
            app.filenameLabel = uilabel(app.editInfoUIFigure);
            app.filenameLabel.Text = 'filename...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = info_edit

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.editInfoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.editInfoUIFigure)
        end
    end
end