classdef ios_scoring < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IOS_postprocessing_tool    matlab.ui.Figure
        LoadButton                 matlab.ui.control.Button
        ParametersPanel            matlab.ui.container.Panel
        FromDropDownLabel          matlab.ui.control.Label
        FromDropDown               matlab.ui.control.DropDown
        ToDropDownLabel            matlab.ui.control.Label
        ToDropDown                 matlab.ui.control.DropDown
        SpFilterEditFieldLabel     matlab.ui.control.Label
        SpFilterEditField          matlab.ui.control.NumericEditField
        SlpFilterLabel             matlab.ui.control.Label
        SlpFilterEditField         matlab.ui.control.NumericEditField
        SlpdifFilterLabel          matlab.ui.control.Label
        SlpdifFilterEditField      matlab.ui.control.NumericEditField
        ROIPanel                   matlab.ui.container.Panel
        AutoButton                 matlab.ui.control.Button
        ThresholdEditFieldLabel    matlab.ui.control.Label
        ThresholdEditField         matlab.ui.control.NumericEditField
        ClosingEditFieldLabel      matlab.ui.control.Label
        ClosingEditField           matlab.ui.control.NumericEditField
        ExcludeartifactsButton     matlab.ui.control.Button
        SelectButton               matlab.ui.control.Button
        RemoveButton               matlab.ui.control.Button
        ResetButtonROI             matlab.ui.control.Button
        ImportButton               matlab.ui.control.Button
        RORPanel                   matlab.ui.container.Panel
        DrawButton                 matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        axImage                    matlab.ui.control.UIAxes
        axTimeline                 matlab.ui.control.UIAxes
        axSlope                    matlab.ui.control.UIAxes
        axROR                      matlab.ui.control.UIAxes
        UpdateButton               matlab.ui.control.Button
        previewTable               matlab.ui.control.Table
        fileListTable              matlab.ui.control.Table
        AnalysisPreviewLabel       matlab.ui.control.Label
        FilelistLabel              matlab.ui.control.Label
        ExportPanel                matlab.ui.container.Panel
        ExportAnalysisButton       matlab.ui.control.Button
        SavecurrentImageButton     matlab.ui.control.Button
        SavecurrenttimelineButton  matlab.ui.control.Button
        EditInfosButton            matlab.ui.control.Button
        gatherdataButton           matlab.ui.control.Button
        StatusLamp                 matlab.ui.control.Lamp
        statusBar                  matlab.ui.control.Label
        LeonardoLupori2020Label    matlab.ui.control.Label
        InvertButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        deafultPath = 'C:\';    % defaultPath
        timelineFullImg         % Timeline of the full image
        timelineROI             % Timeline of the signal in the ROI
        timelineROR             % Timeline of the signal in the ROR
        lowPassTimeline         % Low Passed timeline for slope calculation
        indMinSlope             % Time index of the minimum slope of lowPassTimeline
        imageToDisplay          % 2D image that is displayed
        timelineToDisplay       % 1D timeline to display
        ax                      % Axes handle for plotting the images
        imageHandle             % Handle of the image displayed
        movies                  % 3D or 4D matrix of all the raw movies
        filteredMovies          % 3D or 4D matrix of all the space-filtered movies
        filterUsed              % Size of the filter used to create the current "filteredMovies"
        avgFilteredMovie        % 3D average space-filtered movie
        time                    % time vector
        stimulus                % Definition of the stimulus
        ROImask                 % 2D logical mask for the ROI
        RORmask                 % 2D logical mask for the ROR
        indexLimits             % indexes of the time limits for the analysis
        timeLimits = [0.5 2.5]; % Default time window for the analysis (in seconds)
        drawingMode = false;    % Visualization mode in which the image is displayed with less cluttering elements
        artifactMask            % Mask for part of the image to exclude from the autoROI calculation
        forceFiltering = false; % Force to filter the movies even if the filtersize didn't change. 
        resPreview
        infosApp

    end
    
    properties (Access = public)
        drawnMask
        drawingFig
        roiHandle
        userDefinedInfo
    end
    
    methods (Access = private)
        
        function doPreprocessing(~,PathName,FileName)
            if iscell(FileName)
                % Multiple files have been selected so FileName is a cell of strings
                for i = 1:length(FileName)
                    m = matfile([PathName FileName{i}]);
                    if ~misField(m,'drorMovie')
                        preprocComplete = ios_preprocessing([PathName FileName{i}]);
                        if ~preprocComplete
                            error(['Preprocessing failed while processing file: "' FileName{i} '".']);
                        end
                    end
                    if i == 1
                        movieSize = size(m.drorMovie);
                    else
                        isEqualInSize = movieSize == size(m.drorMovie);
                        if sum(isEqualInSize) ~= 3
                            error(['Mismatch in the movie size of file: "' FileName{i} '".'])
                        end
                    end
                end
            else
                % Only one file has been selected
                m = matfile([PathName FileName]);
                if ~misField(m,'drorMovie')
                    preprocComplete = ios_preprocessing([PathName FileName]);
                    if ~preprocComplete
                        error(['Preprocessing failed while processing file: "' FileName '".']);
                    end
                end
            end
        end
        
        function [movies, time, stimulus, eyes] = collectData(~, PathName, FileName)
            if iscell(FileName)
                % Multiple files have been selected so FileName is a cell of strings
                numOfFiles = length(FileName);
                for i = 1:numOfFiles
                    m = matfile([PathName FileName{i}]);
                    if i == 1
                        movieSize = size(m.drorMovie);
                        % Initialize variables only on the first iteration
                        eyes = cell(numOfFiles,1);
                        movies = zeros([movieSize numOfFiles]);
                        time = m.time;
                        stimulus = m.stimulus;
                    end
                    eyes{i} = m.shutter;
                    movies(:,:,:,i) = m.drorMovie;
                end
            else
                % Only one file has been selected
                m = matfile([PathName FileName]);
                eyes = m.shutter;
                movies = m.drorMovie;
                time = m.time;
                stimulus = m.stimulus;
            end
        end
        
        function setDefaultDropDown(app)
            convert = @(X) num2str(X,'%3.1f');
            strList = cellfun(convert,num2cell(app.time'),'UniformOutput',false);
            % Update the list of values
            app.FromDropDown.Items = strList; app.FromDropDown.ItemsData = app.time;
            app.ToDropDown.Items = strList; app.ToDropDown.ItemsData = app.time;
            % Select the values
            app.FromDropDown.Value = app.timeLimits(1); app.ToDropDown.Value = app.timeLimits(2);
        end
        
        function updateFileList(app,FileName,eye,valid)
            if iscell(FileName)
                if nargin<4
                    valid = true(length(FileName),1);
                end
                app.fileListTable.Data = table(FileName',eye,valid,...
                    'VariableNames',{'fileName','eye','valid'});
            else
                if nargin<4
                    valid = true;
                end
                app.fileListTable.Data = table({FileName},{eye},valid,...
                    'VariableNames',{'fileName','eye','valid'});
            end
        end
        
        function drawImage(app)
            % If the axis is empty initialize an image handle
            if isempty(app.axImage.Children)
                app.imageHandle = imshow(255*ones(size(app.movies,1),size(app.movies,2),'uint8'),...
                    'parent',app.axImage,'InitialMag','fit');
                colormap(app.axImage,'gray');
            end
            img = uint8(mat2gray(app.imageToDisplay-min(app.imageToDisplay(:)))*255);
            if ~isempty(app.ROImask)
                border = boundarymask(app.ROImask);
                img = imoverlay(img, border,[0 0 1]);
            end
            if ~isempty(app.RORmask)
                border = boundarymask(app.RORmask);
                img = imoverlay(img, border,[0 1 0]);
            end
            app.imageHandle.CData = img;
            disableDefaultInteractivity(app.axImage)
        end
        
        function processing(app)
            % Check if the sp filter has changed and filter the movie again
            if app.forceFiltering || isempty(app.filterUsed) || app.SpFilterEditField.Value ~= app.filterUsed
                app.forceFiltering = false;
                app.filteredMovies = filterMov(app,app.movies,app.SpFilterEditField.Value);
                app.avgFilteredMovie = mean(app.filteredMovies,4);
                app.filterUsed = app.SpFilterEditField.Value;
            end
            % Calculate the mean image to display based on the time limits
            app.indexLimits = [find(app.FromDropDown.ItemsData == app.FromDropDown.Value), find(app.ToDropDown.ItemsData == app.ToDropDown.Value)];
            app.imageToDisplay = mean(app.avgFilteredMovie(:,:,app.indexLimits(1):app.indexLimits(2)),3);
            if  ~isempty(app.artifactMask)
                app.imageToDisplay(app.artifactMask) = mean(app.imageToDisplay(:));
            end
            % Calculate the timelines (FullImg, ROI and ROR)
            app.timelineFullImg = timeline(app.filteredMovies);
            if isempty(app.ROImask) % ROI
                app.timelineROI = [];
            else
                app.timelineROI = timeline(app.filteredMovies,app.ROImask);
                meanRoiTl = mean(app.timelineROI,2);
                app.resPreview.AvgAmp = mean(meanRoiTl(app.indexLimits(1):app.indexLimits(2)));
                [app.resPreview.PeakAmp, ind] = min(meanRoiTl(app.indexLimits(1):app.indexLimits(2)));
                ind = ind+app.indexLimits(1)-1;
                app.resPreview.Latency = app.time(ind);
                app.resPreview.SNR = abs(app.resPreview.PeakAmp) / mean(std(app.timelineROI(1:app.stimulus.preStimTriggers,:),[],1));
            end
            if isempty(app.RORmask) % ROR
                app.timelineROR = [];
            else
                app.timelineROR = timeline(app.filteredMovies,app.RORmask);
            end
            if ~isempty(app.ROImask) && ~isempty(app.RORmask) % RerefTimeline
                rerefTimeline = app.timelineROI-app.timelineROR;
                meanRerefTL = mean(rerefTimeline,2);
                app.resPreview.AvgAmp_Reref = mean(meanRerefTL(app.indexLimits(1):app.indexLimits(2)));
                [app.resPreview.PeakAmp_Reref, ind] = min(meanRerefTL(app.indexLimits(1):app.indexLimits(2)));
                ind = ind+app.indexLimits(1)-1;
                app.resPreview.Latency_Reref = app.time(ind);
                app.resPreview.SNR_Reref = abs(app.resPreview.PeakAmp_Reref) / mean(std(rerefTimeline(1:app.stimulus.preStimTriggers,:),[],1));
            end
            % Calculate the slope parameters
            if isempty(app.ROImask) && isempty(app.RORmask)
                app.lowPassTimeline = smooth(mean(app.timelineFullImg,2),app.SlpFilterEditField.Value,'moving');
            elseif ~isempty(app.ROImask) && isempty(app.RORmask)
                app.lowPassTimeline = smooth(meanRoiTl,app.SlpFilterEditField.Value,'moving');
            elseif isempty(app.ROImask) && ~isempty(app.RORmask)
                rorSubtracted = app.timelineFullImg-app.timelineROR;
                app.lowPassTimeline = smooth(mean(rorSubtracted,2),app.SlpFilterEditField.Value,'moving');
            else
                app.lowPassTimeline = smooth(meanRerefTL,app.SlpFilterEditField.Value,'moving');
            end
            derivative = [smooth(diff(app.lowPassTimeline),app.SlpdifFilterEditField.Value,'moving'); 0];
            [app.resPreview.Slope,app.indMinSlope] = min(derivative);
            %app.indMinSlope = ind+app.indexLimits(1)-1;
        end
        
        function filteredMovie = filterMov(~,movie,size)
            % Determine if there is GPU available to speed up the filtering
            try
                canUseGPU = parallel.gpu.GPUDevice.isAvailable;
            catch
                canUseGPU = false;
            end
            % Applies a circular kernel average filter to the 3D or 4D movie
            kernel = fspecial('disk',size);
            if canUseGPU
                movieGpu = gpuArray(movie);
                filteredMovie = imfilter(movieGpu,kernel,'symmetric');
                filteredMovie = gather(filteredMovie);
            else
                filteredMovie = imfilter(movie,kernel,'symmetric');
            end
        end
        
        function drawMainTimeline(app)
            delete(app.axTimeline.Children)
            % Define what to plot
            if isempty(app.ROImask) && isempty(app.RORmask)
                tlToPlot = app.timelineFullImg;
                name = 'Full Img';
            elseif ~isempty(app.ROImask) && isempty(app.RORmask)
                tlToPlot = app.timelineROI;
                name = 'ROI';
            elseif isempty(app.ROImask) && ~isempty(app.RORmask)
                tlToPlot = app.timelineFullImg-app.timelineROR;
                name = 'FullImg-ROR';
            else
                tlToPlot = app.timelineROI-app.timelineROR;
                name = 'ROI-ROR';
            end
            % Plotting code
            app.timelineToDisplay = mean(tlToPlot,2);
            [m, ind] = min(app.timelineToDisplay(app.indexLimits(1):app.indexLimits(2)));
            ind = ind+app.indexLimits(1)-1;
            p = plot(app.axTimeline, app.time, app.timelineToDisplay,...
                'DisplayName',name,...
                'Color',[.8 .2 .2],...
                'LineWidth',2.5);
            hold(app.axTimeline,'on')
            plot(app.axTimeline, app.time, tlToPlot,...
                'Color',[.78 .78 1],...
                'LineWidth',.7);
            drawTimelineRefs(app.axTimeline);
            rectFrom = [app.FromDropDown.Value, app.axTimeline.YLim(1)];
            rectWH = [app.ToDropDown.Value-app.FromDropDown.Value, app.axTimeline.YLim(2)-app.axTimeline.YLim(1)];
            rectangle(app.axTimeline,'Position',[rectFrom(1) rectFrom(2)*0.99 rectWH(1) rectWH(2)],...
                'FaceColor',[0.97 0.97 0.97],'LineStyle','none');
            app.axTimeline.Children = flip(app.axTimeline.Children);
            plot(app.axTimeline,app.time(ind),m,'color','k','Marker','square','MarkerSize',7,'LineWidth',2)
            hold(app.axTimeline,'off')
            legend(app.axTimeline,p,'location','best')
            disableDefaultInteractivity(app.axTimeline)
        end
        
        function drawSlopeTimeline(app)
            delete(app.axSlope.Children)
            window = 10; % how many points before and after plot
            Xindex = [app.indMinSlope-window, app.indMinSlope+window];
            if Xindex(1)<1
                Xindex(1) = 1;
            end
            if Xindex(2)>length(app.time)
                Xindex(2) = length(app.time);
            end
            line(app.axSlope,[app.time(Xindex(1)),  app.time(Xindex(2))],...
                [app.lowPassTimeline(app.indMinSlope)-(app.resPreview.Slope*(app.indMinSlope-Xindex(1))),...
                app.lowPassTimeline(app.indMinSlope)+(app.resPreview.Slope*(Xindex(2)-app.indMinSlope))],...
                'color',[0 0 1],'lineWidth',1.3);
            hold(app.axSlope,'on')
            p = plot(app.axSlope, app.time, app.lowPassTimeline,...
                'DisplayName','Low-Passed',...
                'Color',[.8 .2 .2],...
                'LineWidth',2.5);
            q = plot(app.axSlope, app.time, app.timelineToDisplay,...
                'DisplayName','Raw',...
                'Color',[.2 .8 .2],...
                'LineWidth',1);
            drawTimelineRefs(app.axSlope);
            rectFrom = [app.FromDropDown.Value, app.axSlope.YLim(1)];
            rectWH = [app.ToDropDown.Value-app.FromDropDown.Value, app.axSlope.YLim(2)-app.axSlope.YLim(1)];
            rectangle(app.axSlope,'Position',[rectFrom(1) rectFrom(2)*0.98 rectWH(1) rectWH(2)],...
                'FaceColor',[0.97 0.97 0.97],'LineStyle','none');
            app.axSlope.Children = flip(app.axSlope.Children);
            hold(app.axSlope,'off')
            legend(app.axSlope,[p,q],'location','best')
            disableDefaultInteractivity(app.axSlope)
        end
        
        function drawROIRORtimeline(app)
            delete(app.axROR.Children)
            hold(app.axROR,'on')
            handlesForLegend = [];
            app.axROR.YLim = app.axTimeline.YLim;
            app.axROR.XLim = app.axTimeline.XLim;
            % Draw the ROR timelines if present
            if ~isempty(app.RORmask)
                p = plot(app.axROR, app.time, mean(app.timelineROR,2),...
                    'DisplayName','ROR',...
                    'Color',[.2 .8 .2],...
                    'LineWidth',2.5);
                plot(app.axROR, app.time, app.timelineROR,...
                    'Color',[.78 1 .78],...
                    'LineWidth',.7);
                handlesForLegend = [handlesForLegend p];
            end
            % Draw the ROI timelines if present
            if ~isempty(app.ROImask)
                p = plot(app.axROR, app.time, mean(app.timelineROI,2),...
                    'DisplayName','ROI',...
                    'Color',[.2 .2 .8],...
                    'LineWidth',2.5);
                plot(app.axROR, app.time, app.timelineROI,...
                    'Color',[.78 .78 1],...
                    'LineWidth',.7);
                handlesForLegend = [handlesForLegend p];
            end
            % Draw common elements
            drawTimelineRefs(app.axROR);
            rectFrom = [app.FromDropDown.Value, app.axROR.YLim(1)];
            rectWH = [app.ToDropDown.Value-app.FromDropDown.Value, app.axROR.YLim(2)-app.axROR.YLim(1)];
            rectangle(app.axROR,'Position',[rectFrom(1) rectFrom(2)*0.99 rectWH(1) rectWH(2)],...
                'FaceColor',[0.97 0.97 0.97],'LineStyle','none');
            app.axROR.Children = flip(app.axROR.Children);
            if ~isempty(handlesForLegend)
                legend(app.axROR,handlesForLegend,'location','best')
            end
            hold(app.axROR,'off')
            disableDefaultInteractivity(app.axROR)
        end
        
        function cleanup(app)
            app.indexLimits = [];
            app.timelineFullImg = [];
            app.timelineROI = [];
            app.timelineROR = [];
            app.lowPassTimeline = [];
            app.indMinSlope = [];
            app.imageToDisplay = [];
            app.timelineToDisplay = [];
            app.movies = [];
            app.filteredMovies = [];
            app.filterUsed = [];
            app.avgFilteredMovie = [];
            app.time = [];
            app.stimulus = [];
            app.ROImask = [];
            app.RORmask = [];
            app.artifactMask = [];
            app.forceFiltering = false;
            initializeResultPreview(app)
        end
        
        function updateGraphics(app)
            app.statusBar.Text = 'Processing files...'; app.StatusLamp.Color = [.78 .78 0]; 
            initializeResultPreview(app); drawnow 
            processing(app)
            app.statusBar.Text = 'Updating plots...'; drawnow
            drawImage(app)
            drawMainTimeline(app)
            drawSlopeTimeline(app)
            drawROIRORtimeline(app)
            app.previewTable.Data = table(fieldnames(app.resPreview),struct2cell(app.resPreview));
            app.statusBar.Text = 'Ready...'; app.StatusLamp.Color = [0 1 0]; drawnow
        end
        
        function drawingFunction(app)
            % Create another figure for drawing 
            if exist('drawingFig','var') && ishandle(app.drawingFig)
                delete(app.drawingFig)
            end
            app.drawingFig = figure('Position',[580 330 770 670],...
                'ToolBar','none','Name','Drawing Mode',...
                'KeyPressFcn',@(src,event) detectKey(src,event,app));
            drawingAx = axes(app.drawingFig,'Box','on',...
                'XTick', [], 'YTick',[],'DataAspectRatio',[1 1 1],...
                'PlotBoxAspectRatio',[1 1 1],'Units','pixels',...
                'Units','normalized','Position',[0.0377 0.0433 0.7792 0.8955]);
            btnConf = uicontrol(app.drawingFig,'Style','pushbutton',...
                'Units','normalized','Position',[0.8299 0.1328 0.1558 0.0746],'String','Confirm',...
                'FontWeight','bold','FontSize',10,...
                'Callback',{@confirmCB, app});
            btnCancel = uicontrol(app.drawingFig,'Style','pushbutton',...
                'Units','normalized','Position',[0.8299 0.0433 0.1558 0.0746],'String','Clean',...
                'FontWeight','bold','FontSize',10,...
                'Callback',{@cleanCB, app});
            % Plotting the figure
            img = uint8(mat2gray(app.imageToDisplay-min(app.imageToDisplay(:)))*255);
            if ~isempty(app.ROImask)
                border = boundarymask(app.ROImask);
                img = imoverlay(img, border,[0 0 1]);
            end
            if ~isempty(app.RORmask)
                border = boundarymask(app.RORmask);
                img = imoverlay(img, border,[0 1 0]);
            end
            imshow(img,'parent',drawingAx);
            title(drawingAx,'Draw a region of interest...')
            app.roiHandle = drawfreehand(drawingAx,'Deletable',false);
            function confirmCB(src,event,app)
                if ishandle(app.roiHandle)
                    app.drawnMask = createMask(app.roiHandle);
                    close(app.drawingFig)
                end
            end
            function cleanCB(src,event,app)
                if ishandle(app.roiHandle)
                    delete(app.roiHandle)
                    app.roiHandle = drawfreehand(drawingAx,'Deletable',false);
                end   
            end 
            function detectKey(src,event,app)
                if strcmpi(event.Key, 'return')
                    if ishandle(app.roiHandle)
                        app.drawnMask = createMask(app.roiHandle);
                        close(app.drawingFig)
                    end
                end
            end
        end
        
        function initializeResultPreview(app)
            app.resPreview = struct('AvgAmp',NaN,...
                'AvgAmp_Reref', NaN,...
                'PeakAmp',NaN,...
                'PeakAmp_Reref',NaN,...
                'Latency',NaN,...
                'Latency_Reref',NaN,...
                'Slope',NaN,...
                'SNR',NaN,...
                'SNR_Reref',NaN);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                m = matfile('prefs.mat','Writable',true);
                app.deafultPath = m.defPath;
            catch
                app.deafultPath = 'C:\';
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.statusBar.Text = 'Select one or more IOS recordings...'; app.StatusLamp.Color = [.78 .78 0];
            cleanup(app); drawnow
            [FileName,PathName,FilterIndex] = uigetfile(app.deafultPath,'MultiSelect','on');
            if FilterIndex == 0
                app.statusBar.Text = 'Ready...'; app.StatusLamp.Color = [0 1 0];
                return
            end
            app.deafultPath = PathName;
            try
                m = matfile('prefs.mat','Writable',true);
                m.defPath = app.deafultPath;
            catch
            end
            app.statusBar.Text = 'Preprocessing of the files...'; drawnow
            doPreprocessing(app,PathName,FileName);
            app.statusBar.Text = 'Loading files...'; drawnow
            [app.movies,app.time,app.stimulus,eye] = collectData(app,PathName,FileName);
            updateFileList(app,FileName,eye)
            setDefaultDropDown(app)
            updateGraphics(app)
        end

        % Value changed function: FromDropDown
        function FromDropDownValueChanged(app, event)
            if app.FromDropDown.Value >= app.ToDropDown.Value
                ind = find(app.FromDropDown.ItemsData < app.ToDropDown.Value,1,'last');
                app.FromDropDown.Value = app.FromDropDown.ItemsData(ind);
            end
            updateGraphics(app)
        end

        % Value changed function: ToDropDown
        function ToDropDownValueChanged(app, event)
            if app.FromDropDown.Value >= app.ToDropDown.Value
                ind = find(app.ToDropDown.ItemsData > app.FromDropDown.Value,1,'first');
                app.ToDropDown.Value = app.ToDropDown.ItemsData(ind);
            end
            updateGraphics(app)
        end

        % Value changed function: SpFilterEditField
        function SpFilterEditFieldValueChanged(app, event)
            updateGraphics(app)
        end

        % Value changed function: SlpFilterEditField
        function SlpFilterEditFieldValueChanged(app, event)
            updateGraphics(app)            
        end

        % Value changed function: SlpdifFilterEditField
        function SlpdifFilterEditFieldValueChanged(app, event)
            updateGraphics(app)
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app.ROImask = app.imageToDisplay<imThresh(app.imageToDisplay,app.ThresholdEditField.Value,'bottom');
            app.ROImask = imclose(app.ROImask,strel('disk',app.ClosingEditField.Value));
            updateGraphics(app)
        end

        % Button pushed function: ResetButtonROI
        function ResetButtonROIPushed(app, event)
            app.ROImask = [];
            %app.timelineROI = [];
            app.artifactMask = [];
            updateGraphics(app)
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if isempty(app.ROImask)
                return
            end
            drawingFunction(app)
            waitfor(app.drawingFig)
            if ~isempty(app.drawnMask)
                app.ROImask = app.ROImask & app.drawnMask;
                updateGraphics(app)
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if isempty(app.ROImask)
                return
            end
            drawingFunction(app)
            waitfor(app.drawingFig)
            if ~isempty(app.drawnMask)
                app.ROImask = xor(app.ROImask, (app.ROImask & app.drawnMask));
                updateGraphics(app)
            end
        end

        % Button pushed function: ExcludeartifactsButton
        function ExcludeartifactsButtonPushed(app, event)
            drawingFunction(app)
            waitfor(app.drawingFig)
            if ~isempty(app.drawnMask)
                app.artifactMask = app.drawnMask;
                updateGraphics(app)      
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.RORmask = [];
            %app.timelineROR = [];
            updateGraphics(app)
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.RORmask = [];
            drawingFunction(app)
            waitfor(app.drawingFig)
            if ~isempty(app.drawnMask)
                app.RORmask = app.drawnMask;
                updateGraphics(app)
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.statusBar.Text = 'Loading files...'; app.StatusLamp.Color = [.78 .78 0];drawnow
            filesToLoad = app.fileListTable.Data.fileName(app.fileListTable.Data.valid);
            app.forceFiltering = true;
            [app.movies,app.time,app.stimulus,~] = collectData(app,app.deafultPath,filesToLoad);
            updateGraphics(app)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            DialogTitle = 'Select a file for ROI import';
            [FileName,PathName,FilterIndex] = uigetfile([app.deafultPath '*.mat'],DialogTitle);
            if FilterIndex
                m = matfile([PathName FileName]);
                if ~misField(m,'results')
                    return
                else
                    temp = m.results;
                    if ~isfield(temp,'ROImask')
                        return
                    end
                end
                app.ROImask = temp.ROImask;
                updateGraphics(app)
            end
        end

        % Button pushed function: ExportAnalysisButton
        function ExportAnalysisButtonPushed(app, event)
            app.ExportAnalysisButton.Enable = 'off';
            if isempty(app.ROImask)
                msg = 'There is no ROI selected!';
                tit = 'Error';
                uialert(app.IOS_postprocessing_tool,msg,tit)
                app.ExportAnalysisButton.Enable = 'on';
                return
            end
            % info
            app.userDefinedInfo = [];
            app.infosApp = info_dialog(app,app.deafultPath);
            waitfor(app.infosApp)
            if isempty(app.userDefinedInfo)
                return
            end
            % Parameters
            parameters.treshold = app.ThresholdEditField.Value;
            parameters.startFrame = app.indexLimits(1);
            parameters.endFrame = app.indexLimits(2);
            parameters.spatialFilter = app.SpFilterEditField.Value;
            parameters.temporalFilter = 1;
            parameters.roiClosing = app.ClosingEditField.Value;
            parameters.filter4Slope = app.SlpFilterEditField.Value;
            parameters.filter4SlopeDiff = app.SlpdifFilterEditField.Value;
            % Results
            results.fullImgTimeline = app.timelineFullImg;
            results.roiTimeline = app.timelineROI;
            results.avgAmplitude = app.resPreview.AvgAmp;
            results.peakAmplitude = app.resPreview.PeakAmp;
            results.latency2peak = app.resPreview.Latency;
            results.avgImage = app.imageToDisplay;
            results.ROImask = app.ROImask;
            results.area = sum(app.ROImask(:));
            results.SNR = app.resPreview.SNR;
            results.slope = app.resPreview.Slope;
            results.latency2MaxSlope = app.time(app.indMinSlope);
            % ResultsROR
            if ~isempty(app.RORmask)
                resultsROR.fullImgTimeline = app.timelineFullImg;
                resultsROR.rereferencedTimeline = app.timelineROI - app.timelineROR;
                resultsROR.roiTimeline = app.timelineROI;
                resultsROR.rorTimeline = app.timelineROR;
                resultsROR.avgAmplitude = app.resPreview.AvgAmp_Reref;
                resultsROR.peakAmplitude = app.resPreview.PeakAmp_Reref;
                resultsROR.latency2peak = app.resPreview.Latency_Reref;
                resultsROR.avgImage = app.imageToDisplay;
                temp = reshape(app.timelineROR,[1 1 size(app.timelineROR,1) size(app.timelineROR,2)]);
                rerefMovies = bsxfun(@minus,app.filteredMovies,temp);
                avgRereferencedMovie = mean(rerefMovies,4);
                resultsROR.avgRereferencedImage = mean(avgRereferencedMovie(:,:,app.indexLimits(1):app.indexLimits(2)),3);
                resultsROR.ROImask = app.ROImask;
                resultsROR.RORmask = app.RORmask;
                resultsROR.ROIarea = sum(app.ROImask(:));
                resultsROR.RORarea = sum(app.RORmask(:));
                resultsROR.SNR = app.resPreview.SNR_Reref;
                resultsROR.slope = app.resPreview.Slope;
                resultsROR.latency2MaxSlope = app.time(app.indMinSlope);
            else
                resultsROR = [];
            end
            
            % Save the variables
            temp = strrep(app.userDefinedInfo.dateOfAnalysis,'-','');
            temp = strrep(temp,'_','-');
            path = [app.deafultPath 'analysis_' temp];
            
            if ~isempty(app.userDefinedInfo.miceNumber)
                path = [path '_' num2str(app.userDefinedInfo.miceNumber)];
                if ~isempty(app.userDefinedInfo.miceCage)
                    path = [path '-' app.userDefinedInfo.miceCage];
                end
            end
            if ~isempty(app.userDefinedInfo.eye)
                path = [path '_' app.userDefinedInfo.eye];
            end
            path = [path '.mat'];
            info = app.userDefinedInfo;
            uisave({'info','parameters','results','resultsROR'},path)
        end

        % Button pushed function: EditInfosButton
        function EditInfosButtonPushed(app, event)
            info_edit
        end

        % Button pushed function: InvertButton
        function InvertButtonPushed(app, event)
            if isempty(app.fileListTable.Data)
                tit = 'File list empty';
                msg = 'The file list is empty. It''s mpossible to invert the selection.';
                uialert(app.IOS_postprocessing_tool,msg,tit,'Icon','warning')
            else
                app.fileListTable.Data.valid = ~app.fileListTable.Data.valid;
            end
        end

        % Button pushed function: gatherdataButton
        function gatherdataButtonPushed(app, event)
            app.statusBar.Text = 'Saving files...'; app.StatusLamp.Color = [.78 .78 0];drawnow
            dataCollectFun(app.deafultPath);
            app.statusBar.Text = 'Ready...'; app.StatusLamp.Color = [0 1 0]; drawnow
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IOS_postprocessing_tool = uifigure;
            app.IOS_postprocessing_tool.Name = 'IOS postprocessing tool';
            app.LoadButton = uibutton(app.IOS_postprocessing_tool, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Browse the hard disk to select one or more IOS recordings for postprocessing'};
            app.LoadButton.Text = 'Load';
            app.ParametersPanel = uipanel(app.IOS_postprocessing_tool);
            app.ParametersPanel.Title = 'Parameters';
            app.FromDropDownLabel = uilabel(app.ParametersPanel);
            app.FromDropDownLabel.Text = 'From:';
            app.FromDropDown = uidropdown(app.ParametersPanel);
            app.FromDropDown.Items = {};
            app.FromDropDown.ValueChangedFcn = createCallbackFcn(app, @FromDropDownValueChanged, true);
            app.FromDropDown.Tooltip = {'Beginning of the time window (in seconds) for the analysis.'};
            app.FromDropDown.Value = {};
            app.ToDropDownLabel = uilabel(app.ParametersPanel);
            app.ToDropDownLabel.Text = 'To:';
            app.ToDropDown = uidropdown(app.ParametersPanel);
            app.ToDropDown.Items = {};
            app.ToDropDown.ValueChangedFcn = createCallbackFcn(app, @ToDropDownValueChanged, true);
            app.ToDropDown.Tooltip = {'End of the time window (in seconds) for the analysis.'};
            app.ToDropDown.Value = {};
            app.SpFilterEditFieldLabel = uilabel(app.ParametersPanel);
            app.SpFilterEditFieldLabel.Text = 'Sp. Filter';
            app.SpFilterEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SpFilterEditField.Limits = [1 512];
            app.SpFilterEditField.ValueChangedFcn = createCallbackFcn(app, @SpFilterEditFieldValueChanged, true);
            app.SpFilterEditField.Tooltip = {'Radius (in pixels) of the disk used for spatial filtering.'};
            app.SpFilterEditField.Value = 7;
            app.SlpFilterLabel = uilabel(app.ParametersPanel);
            app.SlpFilterLabel.Text = 'Slp. Filter';
            app.SlpFilterEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SlpFilterEditField.Limits = [1 512];
            app.SlpFilterEditField.ValueChangedFcn = createCallbackFcn(app, @SlpFilterEditFieldValueChanged, true);
            app.SlpFilterEditField.Tooltip = {'Window (in samples) for the running average used to smooth the response curve to estimate the slope.'};
            app.SlpFilterEditField.Value = 5;
            app.SlpdifFilterLabel = uilabel(app.ParametersPanel);
            app.SlpdifFilterLabel.Text = 'Slp.dif Filter';
            app.SlpdifFilterEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SlpdifFilterEditField.Limits = [1 512];
            app.SlpdifFilterEditField.ValueChangedFcn = createCallbackFcn(app, @SlpdifFilterEditFieldValueChanged, true);
            app.SlpdifFilterEditField.Tooltip = {'Window (in samples) for the running average used to further smooth the differential of the response curve to estimate the slope.'};
            app.SlpdifFilterEditField.Value = 3;
            app.ROIPanel = uipanel(app.IOS_postprocessing_tool);
            app.ROIPanel.Title = 'ROI';
            app.AutoButton = uibutton(app.ROIPanel, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Tooltip = {'Select automatically an ROI based on the threshold and closing values.'};
            app.AutoButton.Text = 'Auto';
            app.ThresholdEditFieldLabel = uilabel(app.ROIPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ROIPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.Tooltip = {'Intensity threshold. 0.3 means that pixels in the bottom 30% of the values range are selected.'};
            app.ThresholdEditField.Value = 0.3;
            app.ClosingEditFieldLabel = uilabel(app.ROIPanel);
            app.ClosingEditFieldLabel.Text = 'Closing';
            app.ClosingEditField = uieditfield(app.ROIPanel, 'numeric');
            app.ClosingEditField.Limits = [1 512];
            app.ClosingEditField.Tooltip = {'Radius of the morphological imclose to smooth the ROI'};
            app.ClosingEditField.Value = 5;
            app.ExcludeartifactsButton = uibutton(app.ROIPanel, 'push');
            app.ExcludeartifactsButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeartifactsButtonPushed, true);
            app.ExcludeartifactsButton.Tooltip = {'select and eliminate arifacts in the image that make automatic ROI detection hard.'};
            app.ExcludeartifactsButton.Text = 'Exclude artifacts';
            app.SelectButton = uibutton(app.ROIPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Tooltip = {'Isolate portions of the existing ROI.'};
            app.SelectButton.Text = 'Select';
            app.RemoveButton = uibutton(app.ROIPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Tooltip = {'Remove portions from the existing ROI.'};
            app.RemoveButton.Text = 'Remove';
            app.ResetButtonROI = uibutton(app.ROIPanel, 'push');
            app.ResetButtonROI.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonROIPushed, true);
            app.ResetButtonROI.Tooltip = {'Cancel the ROI. Reset any artifact exclusion.'};
            app.ResetButtonROI.Text = 'Reset';
            app.ImportButton = uibutton(app.ROIPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Tooltip = {'Import the ROI from an analysis file.'};
            app.ImportButton.Text = 'Import';
            app.RORPanel = uipanel(app.IOS_postprocessing_tool);
            app.RORPanel.Title = 'ROR';
            app.DrawButton = uibutton(app.RORPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Tooltip = {'Draw a ROR.'};
            app.DrawButton.Text = 'Draw';
            app.ResetButton = uibutton(app.RORPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Delete the ROR.'};
            app.ResetButton.Text = 'Reset';
            app.axImage = uiaxes(app.IOS_postprocessing_tool);
            title(app.axImage, 'Response Image')
            app.axImage.XTick = [];
            app.axImage.YTick = [];
            app.axTimeline = uiaxes(app.IOS_postprocessing_tool);
            title(app.axTimeline, 'Response timeline')
            xlabel(app.axTimeline, 'time (s)')
            ylabel(app.axTimeline, 'Amplitude \DeltaR/R')
            app.axSlope = uiaxes(app.IOS_postprocessing_tool);
            title(app.axSlope, 'Slope')
            app.axROR = uiaxes(app.IOS_postprocessing_tool);
            title(app.axROR, 'ROR | ROI')
            app.UpdateButton = uibutton(app.IOS_postprocessing_tool, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Tooltip = {'Update the analysis based on the selection on files to include.'};
            app.UpdateButton.Text = 'Update';
            app.previewTable = uitable(app.IOS_postprocessing_tool);
            app.previewTable.ColumnName = {'Parameter'; 'Value'};
            app.previewTable.RowName = {};
            app.previewTable.ColumnEditable = false;
            app.fileListTable = uitable(app.IOS_postprocessing_tool);
            app.fileListTable.ColumnName = {'Filename'; 'Eye'; 'Include'};
            app.fileListTable.RowName = {};
            app.fileListTable.ColumnEditable = [false false true];
            app.AnalysisPreviewLabel = uilabel(app.IOS_postprocessing_tool);
            app.AnalysisPreviewLabel.Text = 'Analysis Preview';
            app.FilelistLabel = uilabel(app.IOS_postprocessing_tool);
            app.FilelistLabel.Text = 'File list:';
            app.ExportPanel = uipanel(app.IOS_postprocessing_tool);
            app.ExportPanel.Title = 'Export';
            app.ExportAnalysisButton = uibutton(app.ExportPanel, 'push');
            app.ExportAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAnalysisButtonPushed, true);
            app.ExportAnalysisButton.Tooltip = {'Export an analysis file.'};
            app.ExportAnalysisButton.Text = 'Export Analysis';
            app.SavecurrentImageButton = uibutton(app.ExportPanel, 'push');
            app.SavecurrentImageButton.Enable = 'off';
            app.SavecurrentImageButton.Text = 'Save current Image';
            app.SavecurrenttimelineButton = uibutton(app.ExportPanel, 'push');
            app.SavecurrenttimelineButton.Enable = 'off';
            app.SavecurrenttimelineButton.Text = 'Save current timeline';
            app.EditInfosButton = uibutton(app.ExportPanel, 'push');
            app.EditInfosButton.ButtonPushedFcn = createCallbackFcn(app, @EditInfosButtonPushed, true);
            app.EditInfosButton.Text = 'Edit Infos';
            app.gatherdataButton = uibutton(app.ExportPanel, 'push');
            app.gatherdataButton.ButtonPushedFcn = createCallbackFcn(app, @gatherdataButtonPushed, true);
            app.gatherdataButton.Text = 'gather data';
            app.StatusLamp = uilamp(app.IOS_postprocessing_tool);
            app.statusBar = uilabel(app.IOS_postprocessing_tool);
            app.statusBar.Text = 'Ready...';
            app.LeonardoLupori2020Label = uilabel(app.IOS_postprocessing_tool);
            app.LeonardoLupori2020Label.Text = 'Leonardo Lupori - 2020';
            app.InvertButton = uibutton(app.IOS_postprocessing_tool, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.InvertButton.Tooltip = {'Invert the current selection of the included files.'};
            app.InvertButton.Text = 'Invert';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ios_scoring

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IOS_postprocessing_tool)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IOS_postprocessing_tool)
        end
    end
end